classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        CHANNELLabel           matlab.ui.control.Label
        TRANSMITTERLabel       matlab.ui.control.Label
        FILTERLabel            matlab.ui.control.Label
        NOISELabel             matlab.ui.control.Label
        Label_4                matlab.ui.control.Label
        Label_3                matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        stopButton_2           matlab.ui.control.Button
        plot3                  matlab.ui.control.Button
        play2                  matlab.ui.control.Button
        apply                  matlab.ui.control.Button
        EditField              matlab.ui.control.NumericEditField
        Label                  matlab.ui.control.Label
        plot                   matlab.ui.control.Button
        stop_2                 matlab.ui.control.Button
        play                   matlab.ui.control.Button
        channels               matlab.ui.control.DropDown
        channelsDropDownLabel  matlab.ui.control.Label
        stopButton             matlab.ui.control.Button
        select                 matlab.ui.control.Button
        run                    matlab.ui.control.Button
        selectButton           matlab.ui.control.Button
        frequency              matlab.ui.control.UIAxes
        time                   matlab.ui.control.UIAxes
        frequency4             matlab.ui.control.UIAxes
        time4                  matlab.ui.control.UIAxes
        frequency3             matlab.ui.control.UIAxes
        time3                  matlab.ui.control.UIAxes
        frequency2             matlab.ui.control.UIAxes
        time2                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        audio        
        originalAudio      
        fs 
        player
        audio_conv
        result
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: selectButton
        function selectButtonPushed(app, event)

          [file, path] = uigetfile('*.*');
          fullPath = fullfile(path, file);
          [app.originalAudio,app.fs] = audioread(fullPath);
          app.audio = app.originalAudio;
          
        end

        % Button pushed function: run
        function runPushed(app, event)
          app.player=  audioplayer(app.originalAudio,app.fs)
          play(app.player)
        end

        % Button pushed function: select
        function selectButtonPushed2(app, event)
            app.audio=app.audio(:,1)
            t=(0:length(app.audio)-1) / app.fs;
            plot(app.time,t,app.audio)
            audiof=fftshift(fft(app.audio))
            audiof=abs(audiof)
            freqbase=linspace(-app.fs/2,app.fs/2,length(app.audio))
            plot(app.frequency,freqbase,audiof)
            
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            stop(app.player)
        end

        % Value changed function: channels
        function channelsValueChanged(app, event)
            value = app.channels.Value;
            app.audio = app.originalAudio;
            app.audio=app.audio(:,1)
            t=(0:length(app.audio)-1) / app.fs;

             switch value
                  case 'deltafn'  
                   h= (t==0) 
                 case 'e^(-2*pi*5000t)'
                   h= exp(-2*pi*5000.*t)
                case 'e^(-2*pi*1000t)'
                   h= exp(-2*pi*1000.*t)
                case 'sinc(2*3000t)'
                   h=sinc(2*3000.*t)
                case '2δ(t)+0.5δ(t-1)'
                   h= 2*(t==0)+0.5*(t==1)
                
      
             end
             app.audio_conv = conv(app.audio, h);
             t_conv = (0:length(app.audio_conv)-1) / app.fs;
             plot(app.time2,t_conv,app.audio_conv)
             yy=abs(fftshift(fft(app.audio_conv)));
             freqbase=linspace(-app.fs/2,app.fs/2,length(yy))
             plot(app.frequency2,freqbase,yy)
             
       
        end

        % Drop down opening function: channels
        function channelsDropDownOpening(app, event)
            
        end

        % Button pushed function: play
        function playButtonPushed(app, event)
            value = app.EditField.Value;
            z=value*randn(1,length(app.audio_conv))
            app.result= z+app.audio_conv
            app.player=audioplayer(app.result,app.fs)
            play(app.player)
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Button pushed function: stop_2
        function stop_2ButtonPushed(app, event)
            stop(app.player)
        end

        % Button pushed function: plot
        function plotButtonPushed(app, event)
         t_conv = (0:length(app.audio_conv)-1) / app.fs   
         plot(app.time3,t_conv,app.result)
         yy=abs(fftshift(fft(app.result)));
         freqbase=linspace(-app.fs/2,app.fs/2,length(yy))
         plot(app.frequency3,freqbase,yy)
         
        end

        % Button pushed function: apply
        function applyButtonPushed(app, event)
            yy=fftshift(fft(app.result));
            freqbase=linspace(-app.fs/2,app.fs/2,length(yy))
            absolute=abs(freqbase)>3400
            yy(absolute)=0
            app.result=real(ifft(ifftshift(yy)))
            t_conv = (0:length(app.result)-1) / app.fs 


        end

        % Button pushed function: play2
        function play2ButtonPushed(app, event)

             app.player=audioplayer(app.result,app.fs)
             play(app.player)
             
        end

        % Button pushed function: stopButton_2
        function stopButton_2Pushed(app, event)
            stop(app.player)
        end

        % Button pushed function: plot3
        function plot3ButtonPushed(app, event)
            yy=abs(fftshift(fft(app.result)));
            freqbase=linspace(-app.fs/2,app.fs/2,length(yy))
            absolute=abs(freqbase)>3400
            yy(absolute)=0
            t_conv = (0:length(app.result)-1) / app.fs
            plot(app.time4,t_conv,app.result)
            plot(app.frequency4,freqbase,yy)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.time2 = uiaxes(app.UIFigure);
            title(app.time2, 'time-domain')
            xlabel(app.time2, 'time')
            ylabel(app.time2, 'amplitude')
            zlabel(app.time2, 'Z')
            app.frequency2 = uiaxes(app.UIFigure);
            title(app.frequency2, 'frequency-domain')
            xlabel(app.frequency2, 'f')
            ylabel(app.frequency2, 'magnitude')
            zlabel(app.frequency2, 'Z')
            app.time3 = uiaxes(app.UIFigure);
            title(app.time3, 'time-domain')
            xlabel(app.time3, 'time')
            ylabel(app.time3, 'amplitude')
            zlabel(app.time3, 'Z')
            app.frequency3 = uiaxes(app.UIFigure);
            title(app.frequency3, 'frequency-domain')
            xlabel(app.frequency3, 'f')
            ylabel(app.frequency3, 'magnitude')
            zlabel(app.frequency3, 'Z')
            app.time4 = uiaxes(app.UIFigure);
            title(app.time4, 'time-domain')
            xlabel(app.time4, 'time')
            ylabel(app.time4, 'amplitude')
            zlabel(app.time4, 'Z')
            app.frequency4 = uiaxes(app.UIFigure);
            title(app.frequency4, 'frequency-domain')
            xlabel(app.frequency4, 'f')
            ylabel(app.frequency4, 'magnitude')
            zlabel(app.frequency4, 'Z')
            app.time = uiaxes(app.UIFigure);
            title(app.time, 'time-domain')
            xlabel(app.time, 'time')
            ylabel(app.time, 'amplitude')
            zlabel(app.time, 'Z')
            app.frequency = uiaxes(app.UIFigure);
            title(app.frequency, 'frequency-domain')
            xlabel(app.frequency, 'f')
            ylabel(app.frequency, 'magnitude')
            zlabel(app.frequency, 'Z')
            app.selectButton = uibutton(app.UIFigure, 'push');
            app.selectButton.ButtonPushedFcn = createCallbackFcn(app, @selectButtonPushed, true);
            app.selectButton.Text = 'select';
            app.run = uibutton(app.UIFigure, 'push');
            app.run.ButtonPushedFcn = createCallbackFcn(app, @runPushed, true);
            app.run.Text = 'run';
            app.select = uibutton(app.UIFigure, 'push');
            app.select.ButtonPushedFcn = createCallbackFcn(app, @selectButtonPushed2, true);
            app.select.Text = 'plot';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = {'stop'; ''};
            app.channelsDropDownLabel = uilabel(app.UIFigure);
            app.channelsDropDownLabel.Text = 'channels';
            app.channels = uidropdown(app.UIFigure);
            app.channels.Items = {'deltafn', 'e^(-2*pi*5000t)', 'e^(-2*pi*1000t)', 'sinc(2*3000t)', '2δ(t)+0.5δ(t-1)', '', '', ''};
            app.channels.DropDownOpeningFcn = createCallbackFcn(app, @channelsDropDownOpening, true);
            app.channels.ValueChangedFcn = createCallbackFcn(app, @channelsValueChanged, true);
            app.channels.Value = 'deltafn';
            app.play = uibutton(app.UIFigure, 'push');
            app.play.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.play.Text = 'play';
            app.stop_2 = uibutton(app.UIFigure, 'push');
            app.stop_2.ButtonPushedFcn = createCallbackFcn(app, @stop_2ButtonPushed, true);
            app.stop_2.Text = 'stop';
            app.plot = uibutton(app.UIFigure, 'push');
            app.plot.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plot.Text = 'plot';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'σ';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.apply = uibutton(app.UIFigure, 'push');
            app.apply.ButtonPushedFcn = createCallbackFcn(app, @applyButtonPushed, true);
            app.apply.Text = 'apply';
            app.play2 = uibutton(app.UIFigure, 'push');
            app.play2.ButtonPushedFcn = createCallbackFcn(app, @play2ButtonPushed, true);
            app.play2.Text = 'play';
            app.plot3 = uibutton(app.UIFigure, 'push');
            app.plot3.ButtonPushedFcn = createCallbackFcn(app, @plot3ButtonPushed, true);
            app.plot3.Text = 'plot';
            app.stopButton_2 = uibutton(app.UIFigure, 'push');
            app.stopButton_2.ButtonPushedFcn = createCallbackFcn(app, @stopButton_2Pushed, true);
            app.stopButton_2.Text = {'stop'; ''};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '| | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | |';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '| | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | | |';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '||'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.NOISELabel = uilabel(app.UIFigure);
            app.NOISELabel.Text = 'NOISE';
            app.FILTERLabel = uilabel(app.UIFigure);
            app.FILTERLabel.Text = 'FILTER';
            app.TRANSMITTERLabel = uilabel(app.UIFigure);
            app.TRANSMITTERLabel.Text = 'TRANSMITTER';
            app.CHANNELLabel = uilabel(app.UIFigure);
            app.CHANNELLabel.Text = 'CHANNEL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end